classdef hotel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        AbrirmihabitacinButton          matlab.ui.control.Button
        CheckinButton                   matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        BienvenidoaDIGITALSTAYHOTELLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        CITab                           matlab.ui.container.Tab
        RegresarButton_4                matlab.ui.control.Button
        RegistrarHuellaButton           matlab.ui.control.Button
        Image4                          matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        RegistratuhuellaLabel           matlab.ui.control.Label
        HabitacionesTab                 matlab.ui.container.Tab
        RegresarButton_2                matlab.ui.control.Button
        ABRIRButton                     matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        puedesabrirtuhabitacinsiesrojodebesintentardenuevoLabel  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        NmerodehabitacinEditField       matlab.ui.control.NumericEditField
        NmerodehabitacinEditFieldLabel  matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        AbretuhabitacinLabel            matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CheckinButton
        function CheckinButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.CITab; 
        end

        % Button pushed function: AbrirmihabitacinButton
        function AbrirmihabitacinButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.HabitacionesTab;
        end

        % Callback function
        function AdministradorButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.AdminTab;
        end

        % Callback function
        function ReservarButtonPushed(app, event)


            app.TabGroup.SelectedTab = app.CITab;
        end

        % Button pushed function: RegistrarHuellaButton
        function RegistrarHuellaButtonPushed(app, event)
            checkFolio;
            
        end

        % Button pushed function: RegresarButton_2, RegresarButton_4
        function RegresarButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.HomeTab;
        end

        % Button pushed function: ABRIRButton
        function ABRIRButtonPushed(app, event)
            verificarHuellaGUI;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {652, 652};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {1008, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image2 = uiimage(app.HomeTab);
            app.Image2.ImageSource = 'exterior.jpg';
            app.BienvenidoaDIGITALSTAYHOTELLabel = uilabel(app.HomeTab);
            app.BienvenidoaDIGITALSTAYHOTELLabel.Text = 'Bienvenido a DIGITAL STAY HOTEL';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = '2e337d2b-fafe-459e-8d85-0e31dc4202fc.jpg';
            app.CheckinButton = uibutton(app.HomeTab, 'push');
            app.CheckinButton.ButtonPushedFcn = createCallbackFcn(app, @CheckinButtonPushed, true);
            app.CheckinButton.Text = 'Check-in';
            app.AbrirmihabitacinButton = uibutton(app.HomeTab, 'push');
            app.AbrirmihabitacinButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirmihabitacinButtonPushed, true);
            app.AbrirmihabitacinButton.Text = 'Abrir mi habitación';
            app.CITab = uitab(app.TabGroup);
            app.CITab.Title = 'CI';
            app.RegistratuhuellaLabel = uilabel(app.CITab);
            app.RegistratuhuellaLabel.Text = 'Registra tu huella';
            app.Image_5 = uiimage(app.CITab);
            app.Image_5.ImageSource = '2e337d2b-fafe-459e-8d85-0e31dc4202fc.jpg';
            app.Image4 = uiimage(app.CITab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'fingerprint-8.png');
            app.RegistrarHuellaButton = uibutton(app.CITab, 'push');
            app.RegistrarHuellaButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrarHuellaButtonPushed, true);
            app.RegistrarHuellaButton.Text = 'Registrar Huella';
            app.RegresarButton_4 = uibutton(app.CITab, 'push');
            app.RegresarButton_4.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton_4.Text = 'Regresar';
            app.HabitacionesTab = uitab(app.TabGroup);
            app.HabitacionesTab.Title = 'Habitaciones';
            app.AbretuhabitacinLabel = uilabel(app.HabitacionesTab);
            app.AbretuhabitacinLabel.Text = 'Abre tu habitación';
            app.Image_3 = uiimage(app.HabitacionesTab);
            app.Image_3.ImageSource = '2e337d2b-fafe-459e-8d85-0e31dc4202fc.jpg';
            app.NmerodehabitacinEditFieldLabel = uilabel(app.HabitacionesTab);
            app.NmerodehabitacinEditFieldLabel.Text = 'Número de habitación';
            app.NmerodehabitacinEditField = uieditfield(app.HabitacionesTab, 'numeric');
            app.Label = uilabel(app.HabitacionesTab);
            app.Label.Text = 'Escribe el número correspondiente y da clic en Abrir. Pon tu dedo en el lector. Si el  LED se ilumina verde ';
            app.Image3 = uiimage(app.HabitacionesTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'pngtree-door-clipart-png-image_6535097.png');
            app.puedesabrirtuhabitacinsiesrojodebesintentardenuevoLabel = uilabel(app.HabitacionesTab);
            app.puedesabrirtuhabitacinsiesrojodebesintentardenuevoLabel.Text = ' puedes abrir tu habitación, si es rojo debes intentar de nuevo.';
            app.Lamp = uilamp(app.HabitacionesTab);
            app.ABRIRButton = uibutton(app.HabitacionesTab, 'push');
            app.ABRIRButton.ButtonPushedFcn = createCallbackFcn(app, @ABRIRButtonPushed, true);
            app.ABRIRButton.Text = 'ABRIR';
            app.RegresarButton_2 = uibutton(app.HabitacionesTab, 'push');
            app.RegresarButton_2.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton_2.Text = 'Regresar';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hotel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end